classdef ultrasound_parameter_calculation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TimevaryingAcousticPressurePsptEditFiled  matlab.ui.control.EditField
        TimevaryingAcousticPressurePspteLabel  matlab.ui.control.Label
        DutyCycleDCEditField            matlab.ui.control.NumericEditField
        DutyCycleDCEditFieldLabel       matlab.ui.control.Label
        PulseDurationPDmsEditField      matlab.ui.control.NumericEditField
        PulseDurationPDmsEditFieldLabel  matlab.ui.control.Label
        PeakrarefactionalpressureMPaEditField  matlab.ui.control.NumericEditField
        PeakrarefactionalpressureMPaEditFieldLabel  matlab.ui.control.Label
        AcousticImpedance105RaylsEditField  matlab.ui.control.NumericEditField
        AcousticImpedance105RaylsLabel  matlab.ui.control.Label
        OperatingFrequencyMHzEditField  matlab.ui.control.NumericEditField
        OperatingFrequencyMHzEditFieldLabel  matlab.ui.control.Label
        INPUTS                          matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        RUNButton                       matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        RESULTS                         matlab.ui.control.Label
        SpatialPeakTimeAverageIntensityIsptaWcm2EditField  matlab.ui.control.NumericEditField
        SpatialPeakTimeAverageIntensityIsptaWcm2EditFieldLabel  matlab.ui.control.Label
        SpatialPeakPulseAverageIntensityIsppaWcm2EditField  matlab.ui.control.NumericEditField
        SpatialPeakPulseAverageIntensityIsppaWcm2EditFieldLabel  matlab.ui.control.Label
        MechanicalIndexMIEditField      matlab.ui.control.NumericEditField
        MechanicalIndexMIEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Ultrasound Parameter Calculation';
        end

        % Value changed function: RUNButton
        function RUNButtonValueChanged(app, event)
            value = app.RUNButton.Value;
            fc = app.OperatingFrequencyMHzEditField.Value;
            DC = app.DutyCycleDCEditField.Value/100;
            Z = app.AcousticImpedance105RaylsEditField.Value;
            Prp = app.PeakrarefactionalpressureMPaEditField.Value;
            PD = app.PulseDurationPDmsEditField.Value;
            Psp = app.TimevaryingAcousticPressurePsptEditFiled.Value;
            if contains(Psp,'sin:')
                Psp=erase(Psp,'sin:');
                Psp = str2num(Psp);
                Psp_sin=@(t) (Psp.*sin(t./10^(-6).*pi).*sin(t.*pi./PD)).^2;
                MI = Prp./sqrt(fc);
                Isppa=integral(Psp_sin,0,PD)/Z/PD;
                Ispta = Isppa*DC;
            else
                Psp = str2num(Psp);
                MI = Prp./sqrt(fc);
                Isppa = Psp^2/2/Z;
                Ispta = Isppa*DC;
            end
            app.MechanicalIndexMIEditField.Value=MI;
            app.SpatialPeakPulseAverageIntensityIsppaWcm2EditField.Value=Isppa*10^2;
            app.SpatialPeakTimeAverageIntensityIsptaWcm2EditField.Value=Ispta*10^2;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {333, 333};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {381, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.INPUTS = uilabel(app.GridLayout3);
            app.INPUTS.Text = 'INPUT:';
            app.OperatingFrequencyMHzEditFieldLabel = uilabel(app.GridLayout3);
            app.OperatingFrequencyMHzEditFieldLabel.Text = 'Operating Frequency  (MHz)';
            app.OperatingFrequencyMHzEditField = uieditfield(app.GridLayout3, 'numeric');
            app.OperatingFrequencyMHzEditField.Value = 0.5;
            app.AcousticImpedance105RaylsLabel = uilabel(app.GridLayout3);
            app.AcousticImpedance105RaylsLabel.Text = 'Acoustic Impedance (10^5 Rayls)';
            app.AcousticImpedance105RaylsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PeakrarefactionalpressureMPaEditFieldLabel = uilabel(app.GridLayout3);
            app.PeakrarefactionalpressureMPaEditFieldLabel.Text = 'Peak-rarefactional pressure (MPa)';
            app.PeakrarefactionalpressureMPaEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PeakrarefactionalpressureMPaEditField.Value = 0.5;
            app.PulseDurationPDmsEditFieldLabel = uilabel(app.GridLayout3);
            app.PulseDurationPDmsEditFieldLabel.Text = 'Pulse Duration (PD) (ms)';
            app.PulseDurationPDmsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DutyCycleDCEditFieldLabel = uilabel(app.GridLayout3);
            app.DutyCycleDCEditFieldLabel.Text = ' Duty Cycle (DC) (%)';
            app.DutyCycleDCEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TimevaryingAcousticPressurePspteLabel = uilabel(app.GridLayout3);
            app.TimevaryingAcousticPressurePspteLabel.Text = 'Time-varying Acoustic Pressure (Psp(t))';
            app.TimevaryingAcousticPressurePsptEditFiled = uieditfield(app.GridLayout3, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.MechanicalIndexMIEditFieldLabel = uilabel(app.GridLayout2);
            app.MechanicalIndexMIEditFieldLabel.Text = 'Mechanical Index (MI)';
            app.MechanicalIndexMIEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpatialPeakPulseAverageIntensityIsppaWcm2EditFieldLabel = uilabel(app.GridLayout2);
            app.SpatialPeakPulseAverageIntensityIsppaWcm2EditFieldLabel.Text = 'Spatial-Peak Pulse-Average Intensity (Isppa) (W/cm^2)';
            app.SpatialPeakPulseAverageIntensityIsppaWcm2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpatialPeakTimeAverageIntensityIsptaWcm2EditFieldLabel = uilabel(app.GridLayout2);
            app.SpatialPeakTimeAverageIntensityIsptaWcm2EditFieldLabel.Text = 'Spatial-Peak Time-Average Intensity (Ispta) (W/cm^2)';
            app.SpatialPeakTimeAverageIntensityIsptaWcm2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.RESULTS = uilabel(app.GridLayout2);
            app.RESULTS.Text = 'RESULTS:';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.RUNButton = uibutton(app.GridLayout2, 'state');
            app.RUNButton.ValueChangedFcn = createCallbackFcn(app, @RUNButtonValueChanged, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ultrasound_parameter_calculation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end